classdef PETIntensityNormalize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        PETnamesTextArea             matlab.ui.control.TextArea
        SelectPETImagesButton        matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        SelectReferenceRegionButton  matlab.ui.control.Button
        ReferenceRegionTextArea      matlab.ui.control.TextArea
        RunButton                    matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        PrefixEditFieldLabel         matlab.ui.control.Label
        PrefixEditField              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 510 && h >496
                app.UIFigure.Position = [round((w-510)/2)+1,round((h-496)/2)+1,510,490];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: SelectPETImagesButton
        function SelectPETImagesButtonPushed(app, event)
            PETnames = spm_select(Inf,'image','Select PET images'); 
            if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
            end
        end

        % Button pushed function: SelectReferenceRegionButton
        function SelectReferenceRegionButtonPushed(app, event)
            str = which('PETIntensityNormalize');
            [filepath,~,~] = fileparts(str);
            ReferenceName = spm_select(1,'image','Select Reference Region image',{},[filepath,'\ReferenceRegion']); 
            if ~isempty(ReferenceName)
                app.ReferenceRegionTextArea.Value = cellstr(ReferenceName);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            PETnames = app.PETnamesTextArea.Value;
            ReferenceName = app.ReferenceRegionTextArea.Value;
            prefix = app.PrefixEditField.Value;
            if isempty(PETnames{1,1})
                uialert(app.UIFigure,'No PET image entered.','Alert');
            elseif isempty(ReferenceName{1,1})
                uialert(app.UIFigure,'No Reference Region image entered.','Alert');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Intensity Normalization is in progress');
                IntensityNormalize(PETnames,ReferenceName,prefix,d);
                close(d);
                fprintf('Down: PET Images Intensity Normalization.\n');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PETnamesTextArea = uitextarea(app.GridLayout2);
            app.SelectPETImagesButton = uibutton(app.GridLayout2, 'push');
            app.SelectPETImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPETImagesButtonPushed, true);
            app.SelectPETImagesButton.Text = 'Select PET Images';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SelectReferenceRegionButton = uibutton(app.GridLayout3, 'push');
            app.SelectReferenceRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceRegionButtonPushed, true);
            app.SelectReferenceRegionButton.Text = 'Select Reference Region';
            app.ReferenceRegionTextArea = uitextarea(app.GridLayout3);
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PrefixEditFieldLabel = uilabel(app.GridLayout4);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.GridLayout4, 'text');
            app.PrefixEditField.Value = 'n';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PETIntensityNormalize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end